classdef TableDataAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BloodPressureAnalyisisUIFigure  matlab.ui.Figure
        s_t                             matlab.ui.control.NumericEditField
        s_tEditFieldLabel               matlab.ui.control.Label
        s_r                             matlab.ui.control.NumericEditField
        s_rEditFieldLabel               matlab.ui.control.Label
        r_2                             matlab.ui.control.NumericEditField
        r2EditFieldLabel                matlab.ui.control.Label
        r                               matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        a1                              matlab.ui.control.NumericEditField
        a1EditFieldLabel                matlab.ui.control.Label
        a0                              matlab.ui.control.NumericEditField
        a0EditFieldLabel                matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data 
            x1 =t.x;
            y1 = t.y;
            scatter(app.UIAxes,x1,y1);
            hold(app.UIAxes,"on");

            n=length(t.x);
            sum_x = sum(x1);           
            sum_y = sum(y1);
            sum_x2 = sum(x1.^2);
            sum_xy = sum(x1.*y1); 
            A = [n sum_x sum_y; sum_x sum_x2 sum_xy];
            solution = rref(A);
            a0v = solution(1,3);
            a1v = solution(2,3);
            
            srv = sum( (y1 -a0v -a1v.*x1) .^2 );
            
            stv = sum((y1-(sum_y/n)).^2);
            
            r2v = (stv - srv)/stv;
            
            app.a0.Value=a0v;
            app.a1.Value=a1v;
            app.s_t.Value=stv;
            app.s_r.Value=srv;
            app.r_2.Value=r2v;
            app.r.Value=sqrt(r2v);
            y_num =a1v.*x1+a0v;
            plot(app.UIAxes,x1,y_num,'Color','red');
            hold(app.UIAxes,"off");

            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('data.xlsx');
            vars = {'x','y'};
            
            % Select a subset of the table array
            t = t(1:end,vars);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            % Plot the original data
            x1 = app.UITable.Data.x;
            y1 = app.UITable.Data.y;
            
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x_add=app.XEditField.Value;
            y_add=app.YEditField.Value;
            co_add={x_add y_add};
            t=app.UITable.Data;
            app.UITable.Data=[t;co_add];
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BloodPressureAnalyisisUIFigure = uifigure('Visible', 'off');
            app.BloodPressureAnalyisisUIFigure.Name = 'Blood Pressure Analyisis';
            app.UIAxes = uiaxes(app.BloodPressureAnalyisisUIFigure);
            title(app.UIAxes, 'Original Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.BloodPressureAnalyisisUIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.XEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.AddButton = uibutton(app.BloodPressureAnalyisisUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.a0EditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.a0.Editable = 'off';
            app.a1EditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.a1.Editable = 'off';
            app.rEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.r = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.r.Editable = 'off';
            app.r2EditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.r2EditFieldLabel.Text = 'r^2';
            app.r_2 = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.r_2.Editable = 'off';
            app.s_rEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.s_rEditFieldLabel.Text = 's_r';
            app.s_r = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.s_r.Editable = 'off';
            app.s_tEditFieldLabel = uilabel(app.BloodPressureAnalyisisUIFigure);
            app.s_tEditFieldLabel.Text = 's_t';
            app.s_t = uieditfield(app.BloodPressureAnalyisisUIFigure, 'numeric');
            app.s_t.Editable = 'off';
            % Show the figure after all components are created
            app.BloodPressureAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableDataAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BloodPressureAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BloodPressureAnalyisisUIFigure)
        end
    end
end